classdef BlueWhaleDataExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        DataTab              matlab.ui.container.Tab
        whaleData            matlab.ui.control.Table
        PlotTab              matlab.ui.container.Tab
        WhaleIDListBox       matlab.ui.control.ListBox
        WhaleIDListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        whaleTableData % Table for whales
        plotAxes % Axes for geoplot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load cleanWhaleData5.mat whales

            app.whaleTableData = whales;
            app.plotAxes = geoaxes(app.PlotTab);
            app.plotAxes.Position = [20 20 320 400];
            app.WhaleIDListBox.Items = unique(whales.ID);

            app.whaleData.Data = app.whaleTableData;
            app.whaleData.ColumnName = app.whaleTableData.Properties.VariableNames;
        end

        % Value changed function: WhaleIDListBox
        function WhaleIDListBoxValueChanged(app, event)
            id = app.WhaleIDListBox.Value;
            
            whaleIdx = strcmp(app.whaleTableData.ID, id);
            whale = app.whaleTableData(whaleIdx,:);

             % Update the UI table to show only this whale's data
            app.whaleData.Data = whale;
        
            % Clear previous plot
            cla(app.plotAxes)
        
            % Plot if data is available
            if ~isempty(whale)
                geoplot(app.plotAxes, whale.latitude, whale.longitude, 'b-o', 'LineWidth', 1.5)
                geolimits(app.plotAxes, ...
                          [min(whale.latitude)-0.05, max(whale.latitude)+0.05], ...
                          [min(whale.longitude)-0.05, max(whale.longitude)+0.05])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WhaleIDListBoxLabel = uilabel(app.UIFigure);
            app.WhaleIDListBoxLabel.Text = 'Whale ID';
            app.WhaleIDListBox = uilistbox(app.UIFigure);
            app.WhaleIDListBox.ValueChangedFcn = createCallbackFcn(app, @WhaleIDListBoxValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.whaleData = uitable(app.DataTab);
            app.whaleData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.whaleData.RowName = {};
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BlueWhaleDataExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end